classdef Simulink_Movement_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MetaDataPanel                   matlab.ui.container.Panel
        SelectmetaDOFButton             matlab.ui.control.Button
        SelectmetaSegmentButton         matlab.ui.control.Button
        DegreesofFreedomLabel           matlab.ui.control.Label
        DegreesofFreedomEditField       matlab.ui.control.EditField
        SegmentParametersLabel          matlab.ui.control.Label
        SegmentParametersEditField      matlab.ui.control.EditField
        SimulationDataPanel             matlab.ui.container.Panel
        SelectMovementPathButton        matlab.ui.control.Button
        MovementPathEditFieldLabel      matlab.ui.control.Label
        MovementPathEditField           matlab.ui.control.EditField
        VideoFileNameEditFieldLabel     matlab.ui.control.Label
        VideoFileNameEditField          matlab.ui.control.EditField
        SelectDataPathButton            matlab.ui.control.Button
        DataPathLabel                   matlab.ui.control.Label
        DataPathEditField               matlab.ui.control.EditField
        ModelPanel                      matlab.ui.container.Panel
        SelectModelButton               matlab.ui.control.Button
        ModelNameLabel                  matlab.ui.control.Label
        ModelNameEditField              matlab.ui.control.EditField
        SubjectInformationPanel         matlab.ui.container.Panel
        bMed_ADCheckBox                 matlab.ui.control.CheckBox
        DelsysCheckBox                  matlab.ui.control.CheckBox
        ArrayCheckBox                   matlab.ui.control.CheckBox
        FillmetaTablesCheckBox          matlab.ui.control.CheckBox
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        HandEditField                   matlab.ui.control.EditField
        HandEditFieldLabel              matlab.ui.control.Label
        DateofBirthEditField            matlab.ui.control.EditField
        DateofBirthEditFieldLabel       matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.EditField
        WeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.EditField
        HeightEditFieldLabel            matlab.ui.control.Label
        GeneratemetaTablesButton        matlab.ui.control.Button
        SessionNameEditField            matlab.ui.control.EditField
        SessionNameLabel                matlab.ui.control.Label
        SubjectIdentifierEditField      matlab.ui.control.EditField
        SubjectIdentifierLabel          matlab.ui.control.Label
        ConfigureGUIPanel               matlab.ui.container.Panel
        ipaddressEditField              matlab.ui.control.EditField
        ipaddressLabel                  matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ConfigFileEditField             matlab.ui.control.EditField
        ConfigFileLabel                 matlab.ui.control.Label
        SimModelPanel                   matlab.ui.container.Panel
        PlaybackVideoPauseTimeEditField  matlab.ui.control.NumericEditField
        PlaybackVideoPauseTimeLabel     matlab.ui.control.Label
        NumberofRepsEditField           matlab.ui.control.NumericEditField
        NumberofRepsLabel               matlab.ui.control.Label
        TimeBetweenMovementsEditField   matlab.ui.control.NumericEditField
        TimeBetweenMovementsEditFieldLabel  matlab.ui.control.Label
        PlaybackSpeedRatioEditField     matlab.ui.control.NumericEditField
        PlaybackSpeedRatioEditFieldLabel  matlab.ui.control.Label
        ConstructVideoButton            matlab.ui.control.Button
        PerformReturnMovementsCheckBox  matlab.ui.control.CheckBox
        PlayVideoButton                 matlab.ui.control.Button
        SimTimeEditField                matlab.ui.control.NumericEditField
        SimTimeEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectmetaSegmentButton
        function SelectmetaSegmentButtonPushed(app, event)
            metaSegment = uigetfile('*.csv');
            app.SegmentParametersEditField.Value = metaSegment;
        end

        % Button pushed function: SelectmetaDOFButton
        function SelectmetaDOFButtonPushed(app, event)
            metaDOF = uigetfile('*.csv');
            app.DegreesofFreedomEditField.Value = metaDOF;
        end

        % Callback function
        function SelectMovementButtonPushed(app, event)
            metaTrial = uigetfile('*.csv');
            app.MovementEditField.Value = metaTrial;
        end

        % Button pushed function: SelectDataPathButton
        function SelectDataPathButtonPushed(app, event)
            DataPath = uigetdir;
            app.DataPathEditField.Value = DataPath;
        end

        % Button pushed function: SelectMovementPathButton
        function SelectMovementPathButtonPushed(app, event)
             MovementPath = uigetdir;
             app.MovementPathEditField.Value = MovementPath;
        end

        % Button pushed function: SelectModelButton
        function SelectModelButtonPushed(app, event)
            Model = uigetfile('*.slx');
            app.ModelNameEditField.Value = Model;
        end

        % Button pushed function: GeneratemetaTablesButton
        function GeneratemetaTablesButtonPushed(app, event)
            % Setting the app directory
            sPath = which('Simulink_Movement_App');
            sPath = erase(sPath,"Simulink_Movement_App.mlapp");
            cd(sPath)
            
            setMetaData(app.DataPathEditField.Value,app.SubjectIdentifierEditField.Value,app.SessionNameEditField.Value,app.SexEditField.Value,app.DateofBirthEditField.Value,app.HeightEditField.Value,app.WeightEditField.Value,app.HandEditField.Value,app.TitleEditField.Value,app.bMed_ADCheckBox.Value)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Setting the app directory
            sPath = which('Simulink_Movement_App');
            sPath = erase(sPath,"Simulink_Movement_App.mlapp");
            cd(sPath)
            
            saveConfig(app.ConfigFileEditField.Value,app.SubjectIdentifierEditField.Value,app.SessionNameEditField.Value,app.SexEditField.Value,app.DateofBirthEditField.Value,app.HeightEditField.Value,app.WeightEditField.Value,app.SegmentParametersEditField.Value,app.DegreesofFreedomEditField.Value,app.DataPathEditField.Value,app.MovementPathEditField.Value,app.ModelNameEditField.Value,app.SimTimeEditField.Value,app.VideoFileNameEditField.Value,app.PlaybackSpeedRatioEditField.Value,app.PerformReturnMovementsCheckBox.Value,app.TimeBetweenMovementsEditField.Value,app.PlaybackVideoPauseTimeEditField.Value,app.NumberofRepsEditField.Value,app.FillmetaTablesCheckBox.Value)
             
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Setting the app directory
            sPath = which('Simulink_Movement_App');
            sPath = erase(sPath,"Simulink_Movement_App.mlapp");
            cd(sPath)
            
            configFile = loadConfig(app.ConfigFileEditField.Value);
            
            app.SubjectIdentifierEditField.Value = configFile.sSubject;
            app.SessionNameEditField.Value = configFile.sSession;
            app.SexEditField.Value = configFile.sSex;
            app.DateofBirthEditField.Value = configFile.sDOB;
            app.HeightEditField.Value = configFile.nHeight;
            app.WeightEditField.Value = configFile.nWeight;
            app.SegmentParametersEditField.Value = configFile.sSegmentParameters;
            app.DegreesofFreedomEditField.Value = configFile.sDOF;
            app.DataPathEditField.Value = configFile.sDataPath;
            app.MovementPathEditField.Value = configFile.sMovementPath;
            app.ModelNameEditField.Value = configFile.sModelName;
            app.SimTimeEditField.Value = configFile.tSim;
            app.VideoFileNameEditField.Value = configFile.sVideoFileName;
            app.PlaybackSpeedRatioEditField.Value = configFile.nPlaybackSpeedRatio;
            app.PerformReturnMovementsCheckBox.Value = configFile.nReturnMovements;
            app.TimeBetweenMovementsEditField.Value = configFile.tDelay;
            app.PlaybackVideoPauseTimeEditField.Value = configFile.nPause;
            app.NumberofRepsEditField.Value = configFile.nRep;
            app.FillmetaTablesCheckBox.Value = configFile.bMetaFill;
            
        end

        % Callback function
        function InitializeModelButtonPushed(app, event)
            % Setting the app directory
            sPath = which('Simulink_Movement_App');
            sPath = erase(sPath,"Simulink_Movement_App.mlapp");
            cd(sPath)
            
            sKinFile = run_simbody_hand_20DOF(app.DataPathEditField.Value,app.ModelNameEditField.Value,app.SegmentParametersEditField.Value,app.DegreesofFreedomEditField.Value,app.PerformReturnMovementsCheckBox.Value,app.SimTimeEditField.Value,app.SubjectIdentifierEditField.Value,app.SessionNameEditField.Value,app.MovementPathEditField.Value);
            
            if app.FillmetaTablesCheckBox.Value
                fillMetaData(sKinFile,app.SubjectIdentifierEditField.Value,app.SessionNameEditField.Value,app.DataPathEditField.Value)
            end
        end

        % Button pushed function: ConstructVideoButton
        function ConstructVideoButtonPushed(app, event)
            % Setting the app directory
            sPath = which('Simulink_Movement_App');
            sPath = erase(sPath,"Simulink_Movement_App.mlapp");
            cd(sPath)
            
            sKinFile = vid_simbody_hand_20DOF(app.DataPathEditField.Value,app.ModelNameEditField.Value,app.SegmentParametersEditField.Value,app.DegreesofFreedomEditField.Value,app.PerformReturnMovementsCheckBox.Value,app.SimTimeEditField.Value,app.MovementPathEditField.Value,app.TimeBetweenMovementsEditField.Value,app.NumberofRepsEditField.Value);
            
            if app.FillmetaTablesCheckBox.Value
                fillMetaData(sKinFile,app.SubjectIdentifierEditField.Value,app.SessionNameEditField.Value,app.DataPathEditField.Value,app.DelsysCheckBox.Value,app.ArrayCheckBox.Value)
            end

            writevideo(app.ModelNameEditField.Value,app.DataPathEditField.Value,app.VideoFileNameEditField.Value,app.PlaybackSpeedRatioEditField.Value)
            
        end

        % Button pushed function: PlayVideoButton
        function PlayVideoButtonPushed(app, event)
            sVideoFile = uigetfile('*.avi');
            
            % Setting current directory that contains app
            sPath = what('Simulink Movement App');
            appDirectory(sPath)
            
            playback_simbody_hand_20DOF(app.DataPathEditField.Value,sVideoFile,app.SimTimeEditField.Value,app.NumberofRepsEditField.Value,app.PlaybackVideoPauseTimeEditField.Value,app.ipaddressEditField.Value,app.TimeBetweenMovementsEditField.Value)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimModelPanel = uipanel(app.UIFigure);
            app.SimModelPanel.Title = 'Sim Model';
            app.SimTimeEditFieldLabel = uilabel(app.SimModelPanel);
            app.SimTimeEditFieldLabel.Text = 'Sim Time:';
            app.SimTimeEditField = uieditfield(app.SimModelPanel, 'numeric');
            app.PlayVideoButton = uibutton(app.SimModelPanel, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButtonPushed, true);
            app.PlayVideoButton.Text = 'Play Video';
            app.PerformReturnMovementsCheckBox = uicheckbox(app.SimModelPanel);
            app.PerformReturnMovementsCheckBox.Text = 'Perform Return Movements';
            app.ConstructVideoButton = uibutton(app.SimModelPanel, 'push');
            app.ConstructVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructVideoButtonPushed, true);
            app.ConstructVideoButton.Text = 'Construct Video';
            app.PlaybackSpeedRatioEditFieldLabel = uilabel(app.SimModelPanel);
            app.PlaybackSpeedRatioEditFieldLabel.Text = 'Playback Speed Ratio:';
            app.PlaybackSpeedRatioEditField = uieditfield(app.SimModelPanel, 'numeric');
            app.TimeBetweenMovementsEditFieldLabel = uilabel(app.SimModelPanel);
            app.TimeBetweenMovementsEditFieldLabel.Text = 'Time Between Movements:';
            app.TimeBetweenMovementsEditField = uieditfield(app.SimModelPanel, 'numeric');
            app.NumberofRepsLabel = uilabel(app.SimModelPanel);
            app.NumberofRepsLabel.Text = 'Number of Reps:';
            app.NumberofRepsEditField = uieditfield(app.SimModelPanel, 'numeric');
            app.PlaybackVideoPauseTimeLabel = uilabel(app.SimModelPanel);
            app.PlaybackVideoPauseTimeLabel.Text = 'Playback Video Pause Time:';
            app.PlaybackVideoPauseTimeEditField = uieditfield(app.SimModelPanel, 'numeric');
            app.ConfigureGUIPanel = uipanel(app.UIFigure);
            app.ConfigureGUIPanel.Title = 'Configure GUI';
            app.ConfigFileLabel = uilabel(app.ConfigureGUIPanel);
            app.ConfigFileLabel.Text = 'Config File:';
            app.ConfigFileEditField = uieditfield(app.ConfigureGUIPanel, 'text');
            app.SaveButton = uibutton(app.ConfigureGUIPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.ConfigureGUIPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ipaddressLabel = uilabel(app.ConfigureGUIPanel);
            app.ipaddressLabel.Text = 'ip address:';
            app.ipaddressEditField = uieditfield(app.ConfigureGUIPanel, 'text');
            app.ipaddressEditField.Value = '[]';
            app.SubjectInformationPanel = uipanel(app.UIFigure);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.SubjectIdentifierLabel = uilabel(app.SubjectInformationPanel);
            app.SubjectIdentifierLabel.Text = 'Subject Identifier:';
            app.SubjectIdentifierEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.SessionNameLabel = uilabel(app.SubjectInformationPanel);
            app.SessionNameLabel.Text = 'Session Name:';
            app.SessionNameEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.GeneratemetaTablesButton = uibutton(app.SubjectInformationPanel, 'push');
            app.GeneratemetaTablesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemetaTablesButtonPushed, true);
            app.GeneratemetaTablesButton.Text = 'Generate metaTables';
            app.HeightEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.HeightEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.WeightEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.WeightEditFieldLabel.Text = 'Weight:';
            app.WeightEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.SexEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.SexEditFieldLabel.Text = 'Sex:';
            app.SexEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.DateofBirthEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.DateofBirthEditFieldLabel.Text = 'Date of Birth:';
            app.DateofBirthEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.HandEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.HandEditFieldLabel.Text = 'Hand:';
            app.HandEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.TitleEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.FillmetaTablesCheckBox = uicheckbox(app.SubjectInformationPanel);
            app.FillmetaTablesCheckBox.Text = 'Fill metaTables';
            app.ArrayCheckBox = uicheckbox(app.SubjectInformationPanel);
            app.ArrayCheckBox.Text = 'Array';
            app.DelsysCheckBox = uicheckbox(app.SubjectInformationPanel);
            app.DelsysCheckBox.Text = 'Delsys';
            app.bMed_ADCheckBox = uicheckbox(app.SubjectInformationPanel);
            app.bMed_ADCheckBox.Text = 'bMed_AD';
            app.ModelPanel = uipanel(app.UIFigure);
            app.ModelPanel.Title = 'Model';
            app.ModelNameEditField = uieditfield(app.ModelPanel, 'text');
            app.ModelNameLabel = uilabel(app.ModelPanel);
            app.ModelNameLabel.Text = 'Model Name:';
            app.SelectModelButton = uibutton(app.ModelPanel, 'push');
            app.SelectModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectModelButtonPushed, true);
            app.SelectModelButton.Text = 'Select Model';
            app.SimulationDataPanel = uipanel(app.UIFigure);
            app.SimulationDataPanel.Title = 'Simulation Data';
            app.DataPathEditField = uieditfield(app.SimulationDataPanel, 'text');
            app.DataPathEditField.Value = 'cd';
            app.DataPathLabel = uilabel(app.SimulationDataPanel);
            app.DataPathLabel.Text = 'Data Path:';
            app.SelectDataPathButton = uibutton(app.SimulationDataPanel, 'push');
            app.SelectDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataPathButtonPushed, true);
            app.SelectDataPathButton.Text = 'Select Data Path';
            app.VideoFileNameEditField = uieditfield(app.SimulationDataPanel, 'text');
            app.VideoFileNameEditFieldLabel = uilabel(app.SimulationDataPanel);
            app.VideoFileNameEditFieldLabel.Text = 'Video File Name:';
            app.MovementPathEditField = uieditfield(app.SimulationDataPanel, 'text');
            app.MovementPathEditFieldLabel = uilabel(app.SimulationDataPanel);
            app.MovementPathEditFieldLabel.Text = 'Movement Path:';
            app.SelectMovementPathButton = uibutton(app.SimulationDataPanel, 'push');
            app.SelectMovementPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMovementPathButtonPushed, true);
            app.SelectMovementPathButton.Text = 'Select Movement Path';
            app.MetaDataPanel = uipanel(app.UIFigure);
            app.MetaDataPanel.Title = 'MetaData';
            app.SegmentParametersEditField = uieditfield(app.MetaDataPanel, 'text');
            app.SegmentParametersLabel = uilabel(app.MetaDataPanel);
            app.SegmentParametersLabel.Text = 'Segment Parameters:';
            app.DegreesofFreedomEditField = uieditfield(app.MetaDataPanel, 'text');
            app.DegreesofFreedomLabel = uilabel(app.MetaDataPanel);
            app.DegreesofFreedomLabel.Text = 'Degrees of Freedom:';
            app.SelectmetaSegmentButton = uibutton(app.MetaDataPanel, 'push');
            app.SelectmetaSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmetaSegmentButtonPushed, true);
            app.SelectmetaSegmentButton.Text = 'Select metaSegment';
            app.SelectmetaDOFButton = uibutton(app.MetaDataPanel, 'push');
            app.SelectmetaDOFButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmetaDOFButtonPushed, true);
            app.SelectmetaDOFButton.Text = 'Select metaDOF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulink_Movement_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end